classdef CoApp_vs_Time_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DataFilteringPanel            matlab.ui.container.Panel
        ExcludeDenseDataCheckBox      matlab.ui.control.CheckBox
        ExcludeSparseDataCheckBox     matlab.ui.control.CheckBox
        BaitPhotobleachingStepsLabel  matlab.ui.control.Label
        MaxSpinner                    matlab.ui.control.Spinner
        MaxSpinnerLabel               matlab.ui.control.Label
        MinSpinner                    matlab.ui.control.Spinner
        MinLabel                      matlab.ui.control.Label
        ExponentialDecayFitPanel      matlab.ui.container.Panel
        StartTimeSpinner              matlab.ui.control.Spinner
        StartTimeSpinnerLabel         matlab.ui.control.Label
        EndTimeSpinner                matlab.ui.control.Spinner
        EndTimeSpinnerLabel           matlab.ui.control.Label
        PerformFitButton              matlab.ui.control.Button
        ClearFitsButton               matlab.ui.control.Button
        LoadNewDataButton             matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        XmaxSpinner                   matlab.ui.control.Spinner
        XLimitsSpinnerLabel           matlab.ui.control.Label
        XminSpinner                   matlab.ui.control.Spinner
        CopyPlottoFigureButton        matlab.ui.control.Button
        PreyChannelSelectionPanel     matlab.ui.container.Panel
        PreyCheckBox3                 matlab.ui.control.CheckBox
        PreyCheckBox2                 matlab.ui.control.CheckBox
        PreyCheckBox1                 matlab.ui.control.CheckBox
        ExportPlottoImageButton       matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        matPath % Location of data
        allMatFiles % All of the files to be considered in this analysis
        activeMatFiles % Files we are currently working with
        
        params
        trendWindow = 50; % Adjustable parameter for how many windows will be averaged for the trendline
        
        lowDensity
        highDensity
        totalCounted = struct();
        totalCoApp = struct();
        x
        pctCoApp

        fitCurves = {}; % Holds exponential fit curves
        colors
    end
    
    methods (Access = private)
        
        function app = loadData(app)
            %Get the data from the automated processing
            if ~isempty(app.matPath)
                startPath = app.matPath;
            else
                startPath = pwd;
            end
            app.allMatFiles = uipickfiles('Prompt','Select data files or folders to analyze','Type',{'*.mat'},'FilterSpec',startPath);
            app.activeMatFiles = app.allMatFiles;
            % Set root directory
            slash = strfind(app.allMatFiles{1},filesep);
            app.matPath = app.allMatFiles{1}(1:slash(end));
            
            %% Load data
            fileBar = waitbar(0);
            app.PreyCheckBox1.Visible = true;
            app.PreyCheckBox2.Visible = true;
            app.PreyCheckBox3.Visible = true;
            app.MinSpinner.Visible = true;
            app.MinSpinner.Enable = true;
            app.MaxSpinner.Visible = true;
            app.MaxSpinner.Enable = true;
            app.MaxSpinner.Limits = [1,2];
            app.XmaxSpinner.Limits = [0, Inf];
            for a = 1:length(app.activeMatFiles)  
                % Get image name and root directory
                slash = strfind(app.activeMatFiles{a},filesep);
                fileName = app.activeMatFiles{a}(slash(end)+1:end); 
                if isfolder(app.activeMatFiles{a})
                    fileName = [fileName '.mat'];
                    expDir = app.activeMatFiles{a};
                    if ~isfile([expDir filesep fileName])
                        warndlg(['No .mat file found for selected folder ' expDir]);
                        continue
                    end
                else
                    expDir = app.activeMatFiles{a}(1:slash(end));
                end
                
                % Load data structure
                load([expDir filesep fileName],'dynData','params');
                app.params = params; clear params;
                %Extract channel info - this is just for code readability
                BaitChannel = app.params.BaitChannel;
%                 if ~strcmp(app.params.DataType, 'Composite Data')
%                     PreyChannel = app.params.PreyChannel;
%                 end
                % Figure out what channels are present 
                app.PreyCheckBox1.Text = ['Channel ' num2str(app.params.preyChNums(1))];
                if length(app.params.preyChNums) >= 2
                    app.PreyCheckBox2.Text = ['Channel ' num2str(app.params.preyChNums(2))];
                else
                    app.PreyCheckBox2.Visible = false;
                end
                if length(app.params.preyChNums) >= 3
                    app.PreyCheckBox3.Text = ['Channel ' num2str(app.params.preyChNums(3))];
                else
                    app.PreyCheckBox3.Visible = false;
                end

                % Check if we have photobleaching data
                if isfield(dynData.BaitSpotData,'nFluors')
                    if app.MaxSpinner.Limits(2) < max([dynData.BaitSpotData.nFluors])
                        app.MaxSpinner.Limits(2) = max([dynData.BaitSpotData.nFluors]);
                        app.MaxSpinner.Value = app.MaxSpinner.Limits(2);
                    end
                    app.MinSpinner.Limits = app.MaxSpinner.Limits;
                else
                    app.MinSpinner.Visible = false;
                    app.MinSpinner.Enable = false;
                    app.MaxSpinner.Value = 1;
                    app.MinSpinner.Visible = false;
                    app.MaxSpinner.Enable = false;
                end               

                %% Calculate co-appearance vs. size vs. time
                waitbar((a-1)/length(app.activeMatFiles),fileBar,strrep(['Loading ' fileName],'_','\_'));
                % Convert appearance times from frames to seconds and shift based on the gap between lysis and acquisition
                if ~isnumeric(app.params.elapsedTime)
                    app.params.elapsedTime = 0;
                end
                for c = 1:length(dynData.BaitSpotData)
                    if ~isnumeric(dynData.BaitSpotData(c).appearTime)
                        continue
                    end
                    dynData.BaitSpotData(c).appearTime = dynData.BaitSpotData(c).appearTime * 0.05 + app.params.elapsedTime; %Hard-coded 50 ms exposure time - could be a paramter later if needed 
                end

                lastWindow = max(cell2mat({dynData.([BaitChannel 'SpotData']).appearedInWindow}));
                baitsForDensity = [];
                for b = 1:app.MaxSpinner.Value
                    if isfield(dynData.BaitSpotData,'nFluors')
                        spotChoiceIdx = [dynData.BaitSpotData.nFluors] == b;
                    else
                        spotChoiceIdx = true (1, length(dynData.BaitSpotData));
                    end
                    spotChoiceIdx = spotChoiceIdx & cellfun(@isnumeric, {dynData.BaitSpotData.appearTime}); 
                    % Create structs for each prey's % co-appearance with bait
                    for s = 1:app.params.nChannels
                        if s == app.params.baitChNum
                            continue %Skip the bait channel
                        end
                        preyChannel = ['PreyCh' num2str(s)];
                        colocData = {dynData.([BaitChannel 'SpotData'])(spotChoiceIdx).(['appears_w_' preyChannel])};
                        baitsCounted = zeros(1,lastWindow);
                        coAppearing = zeros(1,lastWindow);
                        for d = 1:lastWindow
                            lowerBound = (d-1) * app.params.window * 0.05;
                            upperBound = d * app.params.window * 0.05; %Hard-coded 50 ms exposure time - could be a paramter later if needed 
                            index = cell2mat({dynData.([BaitChannel 'SpotData'])(spotChoiceIdx).appearTime}) > lowerBound & cell2mat({dynData.([BaitChannel 'SpotData'])(spotChoiceIdx).appearTime}) <= upperBound;
                            baitsCounted(d) = sum(~cellfun(@(x) isempty(x) || isnan(x), colocData(index)));
                            coAppearing(d) = sum(cellfun(@(x) ~isempty(x) && x==true, colocData(index)));
                        end  

                        % Put all the data together. Organization: 
                            % Each metric is saved in a structure with a field for each prey channel.
                            % Each field holds a cell array with one element for each particle size being analyzed (loop b).
                            % In each of these arrays, the horizontal dimension represents time (in windows), and the vertical dimension is for datasets (loop a).
                            % We also sum all of the baitsCounted for purposes of calculating density
                        if a == 1
                            app.totalCounted.(preyChannel){b} = baitsCounted;
                            app.totalCoApp.(preyChannel){b} = coAppearing;
                        else
                            app.totalCounted.(preyChannel){b}(a,1:length(baitsCounted)) = baitsCounted;
                            app.totalCoApp.(preyChannel){b}(a,1:length(coAppearing)) = coAppearing;
                        end
                        if b == 1
                            baitsForDensity = baitsCounted;
                        else
                            baitsForDensity = baitsForDensity + baitsCounted;
                        end
                        clear coAppearing baitsCounted
                    end
                end
                % Add information for filtering low- and high-density data
                lowThreshold = (app.params.imgArea / 1e6) * 0.005; %0.005 molecules / um^2 is the lowest level where measurements are reliable. 
                app.lowDensity(a,1:length(baitsForDensity)) = baitsForDensity < lowThreshold;
                highThreshold = (app.params.imgArea / 1e6) * 0.8; %0.8 molecules / um^2 is the highest level where measurements are reliable. 
                app.highDensity(a,1:length(baitsForDensity)) = baitsForDensity > highThreshold;
            end
            close(fileBar)
        end

        function displayData(app)
            % Clear existing plots
            cla(app.UIAxes)
            % Apply density filters, if applicable
            if app.ExcludeSparseDataCheckBox.Value
                densityIdx = ~app.lowDensity;
            else
                densityIdx = true(size(app.lowDensity));
            end
            if app.ExcludeDenseDataCheckBox.Value
                densityIdx = densityIdx & ~app.highDensity;
            end
            % Condense data and Plot
            app.colors = lines(app.params.nChannels);
            for s = 1:app.params.nChannels
                chIndex = find(app.params.preyChNums == s);
                if isempty(chIndex) || ~app.(['PreyCheckBox' num2str(chIndex)]).Value
                    continue
                end
                preyChannel = ['PreyCh' num2str(s)];
                for d = app.MinSpinner.Value : app.MaxSpinner.Value 
                    % Condense data by pooling selected complex sizes
                     tempCoApp = app.totalCoApp.(preyChannel){d};
                     [sizey,sizex] = size(tempCoApp);
                     tempCoApp(~densityIdx(1:sizey,1:sizex)) = NaN;
                     tempTotal = app.totalCounted.(preyChannel){d};
                     tempTotal(~densityIdx(1:sizey,1:sizex)) = NaN;
                    if d == app.MinSpinner.Value
                        coAppearing = sum(tempCoApp,1,'omitnan');
                        baitsCounted = sum(tempTotal,1,'omitnan');
                    else
                        [~,l] = size(tempCoApp);
                        coAppearing(1:l) = coAppearing(1:l) + sum(tempCoApp,1,'omitnan');
                        baitsCounted(1:l) = baitsCounted(1:l) + sum(tempTotal,1,'omitnan');
                    end
                end
                % Calculate % co-appearance for each prey channel & cluster size with the bait
                app.pctCoApp{s} = 100 * (coAppearing ./ baitsCounted);
                % Calculate moving (weighted) mean
                baitTrend = movsum(baitsCounted,app.trendWindow,'omitnan');
                preyTrend = movsum(coAppearing,app.trendWindow,'omitnan');
                colocTrend = 100 * (preyTrend ./ baitTrend);

                % Calculate total (time-independent) co-appearance
                % totalPctCoApp(d) = 100 * (sum(coAppearing) / sum(baitsCounted) );

                %Plot
                [~,lastWindow] = size(coAppearing);
                app.x = (2.5*[1:lastWindow]);
                plot(app.UIAxes, app.x,app.pctCoApp{s},'o','MarkerSize',2.5,'Color',app.colors(s,:),'DisplayName',preyChannel)
                plot(app.UIAxes, app.x,colocTrend,'-','LineWidth',1,'Color',app.colors(s,:),'DisplayName',preyChannel)
                app.EndTimeSpinner.Limits(2) = max(app.x);
                app.XmaxSpinner.Limits(2) = max(app.x);
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ask user to select data
            app = loadData(app);

            % Load and display data
            displayData(app);

        end

        % Value changed function: ExcludeSparseDataCheckBox
        function ExcludeSparseDataCheckBoxValueChanged(app, event)
            displayData(app);
            
        end

        % Value changed function: ExcludeDenseDataCheckBox
        function ExcludeDenseDataCheckBoxValueChanged(app, event)
            displayData(app);
            
        end

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            if app.MinSpinner.Value > app.MaxSpinner.Value
                app.MaxSpinner.Value = app.MinSpinner.Value;
            end
            
            displayData(app);
            
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
            if app.MaxSpinner.Value < app.MinSpinner.Value
                app.MinSpinner.Value = app.MaxSpinner.Value;
            end
            
            displayData(app);
            
        end

        % Button pushed function: PerformFitButton
        function PerformFitButtonPushed(app, event)
            % Set up fit
            opts = fitoptions('Method','NonlinearLeastSquares',...
               'Lower',[0,-inf,0],...
               'StartPoint',[50,0.01,1]);
            expdecay = fittype('a*exp(-b*x)+c',...
                               'independent','x',...
                               'coefficients',{'a','b','c'},...
                               'options',opts);
            
            % Fit selected data
             for s = 1:app.params.nChannels
                chIndex = find(app.params.preyChNums == s);
                if isempty(chIndex) || ~app.(['PreyCheckBox' num2str(chIndex)]).Value
                    continue
                end
                [~,startWindow] = min( abs( app.x - app.StartTimeSpinner.Value));
                [~,endWindow] = min( abs( app.x - app.EndTimeSpinner.Value));
                y_fit = app.pctCoApp{s}(startWindow:endWindow);
                nanIdx = isnan(y_fit);
                y_fit = y_fit(~nanIdx);
                x_fit = app.x(startWindow:endWindow);
                x_fit = x_fit(~nanIdx);
                [f0, gof0] = fit(x_fit',y_fit',expdecay);
                tempfig = figure('Visible','off');
                axes(tempfig,'XLim',[app.StartTimeSpinner.Value app.EndTimeSpinner.Value]);
                fitPlot = plot(f0,'predobs');
                app.fitCurves{end+1} = copyobj(fitPlot, app.UIAxes);
                set(app.fitCurves{end},'Color',app.colors(s,:))
                delete(tempfig);
                f0_CI = confint(f0);
                disp('')
                disp(['Results of fit for channel ' num2str(s)])
                disp(['   Peq (uncorrected) = ' num2str( f0.c ) '%' ])
                disp(['   koff = ' num2str( (1 - f0.c/100)*f0.b ) ' s^-1   (' num2str( (1 - f0.c/100)*f0_CI(1,2) ) ', ' num2str( (1 - f0.c/100)*f0_CI(2,2) ) ')'])
                disp(['   R^2 = ' num2str(gof0.adjrsquare)])
            end

        end

        % Button pushed function: ClearFitsButton
        function ClearFitsButtonPushed(app, event)
            for a = 1:length(app.fitCurves)
                delete(app.fitCurves{a});
            end
            app.fitCurves = {};
        end

        % Button pushed function: LoadNewDataButton
        function LoadNewDataButtonPushed(app, event)
            % Ask user to select data
            app = loadData(app);

            % Load and display data
            displayData(app);
        end

        % Value changed function: PreyCheckBox1
        function PreyCheckBox1ValueChanged(app, event)
            displayData(app);
            
        end

        % Value changed function: PreyCheckBox2
        function PreyCheckBox2ValueChanged(app, event)
            displayData(app);
            
        end

        % Value changed function: PreyCheckBox3
        function PreyCheckBox3ValueChanged(app, event)
            displayData(app);
            
        end

        % Button pushed function: CopyPlottoFigureButton
        function CopyPlottoFigureButtonPushed(app, event)
            w = app.UIAxes.Position(3);
            h = app.UIAxes.Position(4);
            newFig = figure('Position',[100,100,w+100,h+100]);
            newAxes = copyobj(app.UIAxes,newFig);
            set(newAxes,'Position',[50 50 w h]);
        end

        % Button pushed function: ExportPlottoImageButton
        function ExportPlottoImageButtonPushed(app, event)
            tempfig = figure('Visible','off');
            copyobj(app.UIAxes,tempfig);
            [outfile,outpath] = uiputfile('plot.pdf','Save File Name');
            if verLessThan('matlab','9.8')
                export_fig([outpath filesep outfile],newfig1,'-pdf','-png','-transparent');
            else
                exportgraphics(app.generalUIAxes,[outpath filesep outfile],'ContentType','vector');
            end
            close(newfig1);
        end

        % Value changed function: XminSpinner
        function XminSpinnerValueChanged(app, event)
            set(app.UIAxes,'XLim',[app.XminSpinner.Value app.XmaxSpinner.Value]);
            
        end

        % Value changed function: XmaxSpinner
        function XmaxSpinnerValueChanged(app, event)
            set(app.UIAxes,'XLim',[app.XminSpinner.Value app.XmaxSpinner.Value]);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {492, 492};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadNewDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewDataButtonPushed, true);
            app.LoadNewDataButton.Text = 'Load New Data';
            app.ExponentialDecayFitPanel = uipanel(app.LeftPanel);
            app.ExponentialDecayFitPanel.Title = 'Exponential Decay Fit';
            app.ClearFitsButton = uibutton(app.ExponentialDecayFitPanel, 'push');
            app.ClearFitsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFitsButtonPushed, true);
            app.ClearFitsButton.Text = 'Clear Fits';
            app.PerformFitButton = uibutton(app.ExponentialDecayFitPanel, 'push');
            app.PerformFitButton.ButtonPushedFcn = createCallbackFcn(app, @PerformFitButtonPushed, true);
            app.PerformFitButton.Text = 'Perform Fit';
            app.EndTimeSpinnerLabel = uilabel(app.ExponentialDecayFitPanel);
            app.EndTimeSpinnerLabel.Text = 'End Time';
            app.EndTimeSpinner = uispinner(app.ExponentialDecayFitPanel);
            app.EndTimeSpinner.Limits = [0 Inf];
            app.EndTimeSpinner.Value = Inf;
            app.StartTimeSpinnerLabel = uilabel(app.ExponentialDecayFitPanel);
            app.StartTimeSpinnerLabel.Text = 'Start Time';
            app.StartTimeSpinner = uispinner(app.ExponentialDecayFitPanel);
            app.StartTimeSpinner.Limits = [0 Inf];
            app.DataFilteringPanel = uipanel(app.LeftPanel);
            app.DataFilteringPanel.Title = 'Data Filtering';
            app.MinLabel = uilabel(app.DataFilteringPanel);
            app.MinLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.DataFilteringPanel);
            app.MinSpinner.Limits = [1 Inf];
            app.MinSpinner.RoundFractionalValues = 'on';
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.MinSpinner.Value = 1;
            app.MaxSpinnerLabel = uilabel(app.DataFilteringPanel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.DataFilteringPanel);
            app.MaxSpinner.Limits = [1 2];
            app.MaxSpinner.RoundFractionalValues = 'on';
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MaxSpinner.Value = 1;
            app.BaitPhotobleachingStepsLabel = uilabel(app.DataFilteringPanel);
            app.BaitPhotobleachingStepsLabel.Text = 'Bait Photobleaching Steps';
            app.ExcludeSparseDataCheckBox = uicheckbox(app.DataFilteringPanel);
            app.ExcludeSparseDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeSparseDataCheckBoxValueChanged, true);
            app.ExcludeSparseDataCheckBox.Text = 'Exclude Sparse Data';
            app.ExcludeDenseDataCheckBox = uicheckbox(app.DataFilteringPanel);
            app.ExcludeDenseDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeDenseDataCheckBoxValueChanged, true);
            app.ExcludeDenseDataCheckBox.Text = 'Exclude Dense Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Time after lysis (s)')
            ylabel(app.UIAxes, '% Co-appearance')
            zlabel(app.UIAxes, 'Z')
            app.ExportPlottoImageButton = uibutton(app.RightPanel, 'push');
            app.ExportPlottoImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlottoImageButtonPushed, true);
            app.ExportPlottoImageButton.Text = 'Export Plot to Image';
            app.PreyChannelSelectionPanel = uipanel(app.RightPanel);
            app.PreyChannelSelectionPanel.Title = 'Prey Channel Selection';
            app.PreyCheckBox1 = uicheckbox(app.PreyChannelSelectionPanel);
            app.PreyCheckBox1.ValueChangedFcn = createCallbackFcn(app, @PreyCheckBox1ValueChanged, true);
            app.PreyCheckBox1.Text = 'Prey 1';
            app.PreyCheckBox1.Value = true;
            app.PreyCheckBox2 = uicheckbox(app.PreyChannelSelectionPanel);
            app.PreyCheckBox2.ValueChangedFcn = createCallbackFcn(app, @PreyCheckBox2ValueChanged, true);
            app.PreyCheckBox2.Text = 'Prey 2';
            app.PreyCheckBox2.Value = true;
            app.PreyCheckBox3 = uicheckbox(app.PreyChannelSelectionPanel);
            app.PreyCheckBox3.ValueChangedFcn = createCallbackFcn(app, @PreyCheckBox3ValueChanged, true);
            app.PreyCheckBox3.Text = 'Prey 3';
            app.PreyCheckBox3.Value = true;
            app.CopyPlottoFigureButton = uibutton(app.RightPanel, 'push');
            app.CopyPlottoFigureButton.ButtonPushedFcn = createCallbackFcn(app, @CopyPlottoFigureButtonPushed, true);
            app.CopyPlottoFigureButton.Text = 'Copy Plot to Figure';
            app.XminSpinner = uispinner(app.RightPanel);
            app.XminSpinner.Limits = [0 Inf];
            app.XminSpinner.ValueChangedFcn = createCallbackFcn(app, @XminSpinnerValueChanged, true);
            app.XLimitsSpinnerLabel = uilabel(app.RightPanel);
            app.XLimitsSpinnerLabel.Text = 'X Limits';
            app.XmaxSpinner = uispinner(app.RightPanel);
            app.XmaxSpinner.Limits = [0 Inf];
            app.XmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @XmaxSpinnerValueChanged, true);
            app.XmaxSpinner.Value = Inf;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoApp_vs_Time_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end